classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        XButton                matlab.ui.control.StateButton
        GrainSizemsKnobLabel   matlab.ui.control.Label
        GrainSizemsKnob        matlab.ui.control.Knob
        SpreadmsKnobLabel      matlab.ui.control.Label
        SpreadmsKnob           matlab.ui.control.Knob
        PosmsKnobLabel         matlab.ui.control.Label
        PosmsKnob              matlab.ui.control.Knob
        FreezeButton           matlab.ui.control.StateButton
        ReverseKnobLabel       matlab.ui.control.Label
        ReverseKnob            matlab.ui.control.Knob
        PitchLabel             matlab.ui.control.Label
        PitchKnob              matlab.ui.control.Knob
        CutoffFreqKnobLabel    matlab.ui.control.Label
        CutoffFreqKnob         matlab.ui.control.Knob
        QKnobLabel             matlab.ui.control.Label
        QKnob                  matlab.ui.control.Knob
        ReverbDryWetKnobLabel  matlab.ui.control.Label
        ReverbDryWetKnob       matlab.ui.control.Knob
    end

    
    properties (Access = public)
        Plot = false; % Description
        Calc_filter = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function grainsizeSliderValueChanged(app, event)
            value = app.grainsizeSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            value = app.XButton.Value;
        end

        % Value changed function: FreezeButton
        function FreezeButtonValueChanged(app, event)
            value = app.FreezeButton.Value;
            if value
                app.Plot = true;
            end
        end

        % Value changed function: PosmsKnob
        function PosmsKnobValueChanged(app, event)
            value = app.PosmsKnob.Value;
            if app.FreezeButton.Value
                app.Plot = true;
            end
        end

        % Value changing function: PosmsKnob
        function PosmsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.PosmsKnob.Value = changingValue;
            if app.FreezeButton.Value
                app.Plot = true;
            end
        end

        % Value changing function: SpreadmsKnob
        function SpreadmsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.SpreadmsKnob.Value = changingValue;
            if app.FreezeButton.Value
                app.Plot = true;
            end
        end

        % Value changed function: SpreadmsKnob
        function SpreadmsKnobValueChanged(app, event)
            value = app.SpreadmsKnob.Value;
            if app.FreezeButton.Value
                app.Plot = true;
            end
        end

        % Value changed function: CutoffFreqKnob
        function CutoffFreqKnobValueChanged(app, event)
            value = app.CutoffFreqKnob.Value;
            app.Calc_filter = true;
        end

        % Value changed function: QKnob
        function QKnobValueChanged(app, event)
            value = app.QKnob.Value;
            app.Calc_filter = true;
        end

        % Value changing function: CutoffFreqKnob
        function CutoffFreqKnobValueChanging(app, event)
            changingValue = event.Value;
            app.CutoffFreqKnob.Value = changingValue;
            app.Calc_filter = true;
        end

        % Value changing function: PitchKnob
        function PitchKnobValueChanging(app, event)
            changingValue = event.Value;
            app.PitchKnob.Value = changingValue;
        end

        % Value changing function: QKnob
        function QKnobValueChanging(app, event)
            changingValue = event.Value;
            app.QKnob.Value = changingValue;
        end

        % Value changing function: GrainSizemsKnob
        function GrainSizemsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GrainSizemsKnob.Value = changingValue;
        end

        % Value changing function: ReverseKnob
        function ReverseKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ReverseKnob.Value = changingValue;
        end

        % Value changed function: ReverbDryWetKnob
        function ReverbDryWetKnobValueChanged(app, event)
            value = app.ReverbDryWetKnob.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 220500];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X';
            app.GrainSizemsKnobLabel = uilabel(app.UIFigure);
            app.GrainSizemsKnobLabel.Text = 'Grain Size (ms)';
            app.GrainSizemsKnob = uiknob(app.UIFigure, 'continuous');
            app.GrainSizemsKnob.Limits = [1 500];
            app.GrainSizemsKnob.MajorTicks = [1 100 200 300 400 500];
            app.GrainSizemsKnob.ValueChangingFcn = createCallbackFcn(app, @GrainSizemsKnobValueChanging, true);
            app.GrainSizemsKnob.Value = 100;
            app.SpreadmsKnobLabel = uilabel(app.UIFigure);
            app.SpreadmsKnobLabel.Text = 'Spread (ms)';
            app.SpreadmsKnob = uiknob(app.UIFigure, 'continuous');
            app.SpreadmsKnob.Limits = [0 2000];
            app.SpreadmsKnob.ValueChangedFcn = createCallbackFcn(app, @SpreadmsKnobValueChanged, true);
            app.SpreadmsKnob.ValueChangingFcn = createCallbackFcn(app, @SpreadmsKnobValueChanging, true);
            app.SpreadmsKnob.Value = 1000;
            app.PosmsKnobLabel = uilabel(app.UIFigure);
            app.PosmsKnobLabel.Text = 'Pos (ms)';
            app.PosmsKnob = uiknob(app.UIFigure, 'continuous');
            app.PosmsKnob.Limits = [0 5000];
            app.PosmsKnob.ValueChangedFcn = createCallbackFcn(app, @PosmsKnobValueChanged, true);
            app.PosmsKnob.ValueChangingFcn = createCallbackFcn(app, @PosmsKnobValueChanging, true);
            app.PosmsKnob.Value = 5000;
            app.FreezeButton = uibutton(app.UIFigure, 'state');
            app.FreezeButton.ValueChangedFcn = createCallbackFcn(app, @FreezeButtonValueChanged, true);
            app.FreezeButton.Text = 'Freeze';
            app.ReverseKnobLabel = uilabel(app.UIFigure);
            app.ReverseKnobLabel.Text = 'Reverse (%)';
            app.ReverseKnob = uiknob(app.UIFigure, 'continuous');
            app.ReverseKnob.MajorTicks = [0 20 40 60 80 100];
            app.ReverseKnob.ValueChangingFcn = createCallbackFcn(app, @ReverseKnobValueChanging, true);
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            app.PitchKnob = uiknob(app.UIFigure, 'continuous');
            app.PitchKnob.Limits = [-24 24];
            app.PitchKnob.MajorTicks = [-24 -12 0 12 24];
            app.PitchKnob.ValueChangingFcn = createCallbackFcn(app, @PitchKnobValueChanging, true);
            app.PitchKnob.MinorTicks = [-22 -18 -14 -10 -6 -2 2 6 10 14 18 22];
            app.CutoffFreqKnobLabel = uilabel(app.UIFigure);
            app.CutoffFreqKnobLabel.Text = 'CutoffFreq';
            app.CutoffFreqKnob = uiknob(app.UIFigure, 'continuous');
            app.CutoffFreqKnob.Limits = [0 1];
            app.CutoffFreqKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.CutoffFreqKnob.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqKnobValueChanged, true);
            app.CutoffFreqKnob.ValueChangingFcn = createCallbackFcn(app, @CutoffFreqKnobValueChanging, true);
            app.CutoffFreqKnob.MinorTicks = [0 0.035625 0.07125 0.106875 0.1425 0.178125 0.21375 0.249375 0.285 0.320625 0.35625 0.391875 0.4275 0.463125 0.49875 0.534375 0.57 0.605625 0.64125 0.676875 0.7125 0.748125 0.78375 0.819375 0.855 0.890625 0.92625 0.961875 1];
            app.CutoffFreqKnob.Value = 0.95;
            app.QKnobLabel = uilabel(app.UIFigure);
            app.QKnobLabel.Text = 'Q';
            app.QKnob = uiknob(app.UIFigure, 'continuous');
            app.QKnob.Limits = [0 1];
            app.QKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.QKnob.ValueChangedFcn = createCallbackFcn(app, @QKnobValueChanged, true);
            app.QKnob.ValueChangingFcn = createCallbackFcn(app, @QKnobValueChanging, true);
            app.QKnob.Value = 0.3;
            app.ReverbDryWetKnobLabel = uilabel(app.UIFigure);
            app.ReverbDryWetKnobLabel.Text = 'Reverb (Dry/Wet)';
            app.ReverbDryWetKnob = uiknob(app.UIFigure, 'continuous');
            app.ReverbDryWetKnob.Limits = [0 1];
            app.ReverbDryWetKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ReverbDryWetKnob.ValueChangedFcn = createCallbackFcn(app, @ReverbDryWetKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end